classdef DealPicture_Gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        Button_11    matlab.ui.control.Button
        Button_9     matlab.ui.control.Button
        Button_8     matlab.ui.control.StateButton
        LoadButton   matlab.ui.control.Button
        TabGroup     matlab.ui.container.TabGroup
        Tab_1        matlab.ui.container.Tab
        GridLayout4  matlab.ui.container.GridLayout
        Button_13    matlab.ui.control.Button
        Button_6     matlab.ui.control.Button
        Button_10    matlab.ui.control.Button
        Button_5     matlab.ui.control.Button
        Tab_2        matlab.ui.container.Tab
        GridLayout2  matlab.ui.container.GridLayout
        Button_4     matlab.ui.control.Button
        Button_12    matlab.ui.control.Button
        Button_3     matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        HSVTab       matlab.ui.container.Tab
        GridLayout3  matlab.ui.container.GridLayout
        Label_4      matlab.ui.control.Label
        Slider_3     matlab.ui.control.Slider
        Label_3      matlab.ui.control.Label
        Slider_2     matlab.ui.control.Slider
        Label_2      matlab.ui.control.Label
        Slider_1     matlab.ui.control.Slider
        ImageAxes    matlab.ui.control.UIAxes
        GreenAxes    matlab.ui.control.UIAxes
        BlueAxes     matlab.ui.control.UIAxes
        RedAxes      matlab.ui.control.UIAxes
    end

    properties (Access = public)
        im
        im_hsv
        im_class
        im_tab
        im_init
    end
    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            try
                if (ischar(imagefile))
                    app.im = imread(imagefile);
                else
                    app.im = imagefile;
                end
                
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end
            
                       % 获取图像颜色通道数
            app.im_class = size(app.im,3);
            
            % 如果app.im_tab为空，则初始化app.im_tab和app.im_init
            if isempty(app.im_tab)
                app.im_tab = app.im;
                app.im_init = app.im;
            end
            
            % 根据颜色通道数创建直方图
            switch app.im_class
                case 1
                    % 如果是灰度图像，显示灰度图像
                    imagesc(app.ImageAxes,app.im);
                    
                    % 对于灰度图像，三个颜色的直方图数据相同
                    histr = histogram(app.RedAxes, app.im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, app.im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, app.im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                    
                case 3
                    % 如果是真彩色图像，显示真彩色图像
                    imagesc(app.ImageAxes,app.im);
                    
                    % 分别绘制红、绿、蓝三个颜色通道的直方图
                    histr = histogram(app.RedAxes, im2uint8(app.im(:,:,1)), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im2uint8(app.im(:,:,2)), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im2uint8(app.im(:,:,3)), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % 如果图像既不是灰度图也不是真彩色图，显示错误信息
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
            
            % 获取三个颜色通道中最大的bin计数
            maxr = max(histr.BinCounts);
            maxg = max(histg.BinCounts);
            maxb = max(histb.BinCounts);
            maxcount = max([maxr maxg maxb]);
            
            % 根据最大的bin计数设置y轴的限制
            app.RedAxes.YLim = [0 maxcount];
            app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            app.GreenAxes.YLim = [0 maxcount];
            app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            app.BlueAxes.YLim = [0 maxcount];
            app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            
        end


        function reset(app,mode)
            if mode == 0
                app.TabGroup.SelectedTab = app.Tab_1;
                app.Slider_1.Value = 0;
                app.Slider_2.Value = 0;
                app.Slider_3.Value = 0;
                if(app.im_class == 3)
                    app.im_hsv = rgb2hsv(app.im);
                    app.Button_10.Enable = 'on';
                end
                if(app.im_class == 1)
                    app.Button_10.Enable = 'off';
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
            updateimage(app, 'Logo.jpg');
        end

        % Callback function
        function DropDownValueChanged(app, event)

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];
                updateimage(app, fname);
                app.im_init = app.im;
                reset(app,0)
            end
        end

        % Value changed function: Button_8
        function compare(app, event)
            
            if app.Button_8.Value == 1
                im_0 = app.im;
                updateimage(app,app.im_init);
                app.im = im_0;
            else
                
                updateimage(app,app.im);
                
            end
        end

        % Button pushed function: Button_9
        function recovery(app, event)
            updateimage(app,app.im_init);
            reset(app,0);
        end

        % Button pushed function: Button_11
        function export(app, event)
            imsave(app.ImageAxes);
        end

        % Size changed function: HSVTab, Tab_1, Tab_2
        function secTab(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            app.im_tab = app.im;
            if (selectedTab == app.HSVTab)
                if (app.im_class == 3)
                    app.im_hsv = rgb2hsv(app.im);
                    app.Slider_2.Enable = 'on';
                    app.Slider_3.Enable = 'on';
                else
                    app.Slider_1.Value = 0;
                    app.Slider_1.Enable = 'off';
                    app.Slider_2.Value = 0;
                    app.Slider_2.Enable = 'off';
                    app.Slider_3.Value = 0;
                    app.Slider_3.Enable = 'off';
                    
                end
                
            end
            
        end

        % Button pushed function: Button_5
        function denoise(app, event)
            %主要通过使用Wiener滤波方法对图像进行处理。
            % Wiener滤波是一种自适应滤波技术，用于图像去噪，特别适合去除随机噪声，如高斯噪声。
            % 该方法在尝试去除噪声的同时，尽可能保留图像的细节和边缘。
            %wiener2
            %I：输入图像，可以是灰度图像或单个颜色通道的图像。
            %wiener2（输入图像，滤波器算子，噪声方差）
            if app.im_class == 3
                J(:,:,1) = wiener2(app.im(:,:,1),[3 3]);
                J(:,:,2) = wiener2(app.im(:,:,2),[3 3]);
                J(:,:,3) = wiener2(app.im(:,:,3),[3 3]);
                updateimage(app,J);
            else
                J = wiener2(app.im,[3 3]);
                updateimage(app,J);
            end
        end

        % Button pushed function: Button_6
        function CLAHE(app, event)
            %主要通过对图像的亮度通道进行自适应直方图均衡化来完成。
            if (app.im_class == 3)
                lab_image = rgb2lab(app.im);
                % 提取亮度通道,将值缩放到 adapthisteq 函数预期的范围 [0 1]。
                luminance = lab_image(:, :, 1)/100;
                % 对亮度通道进行自适应直方图均衡化,缩放结果，使其回到 L*a*b* 颜色空间使用的范围。
                enhanced_luminance = adapthisteq(luminance)*100;
                % 替换原来的亮度通道
                lab_image(:,:,1) = enhanced_luminance;
                % 将图像转换回RGB空间
                enhanced_image = lab2rgb(lab_image);
                updateimage(app,enhanced_image);
            else
                J = adapthisteq(app.im);
                updateimage(app,J);
            end
        end

        % Button pushed function: Button_13
        function gamma(app, event)
            if (app.im_class == 3)
                sRGB = lin2rgb(app.im);
                updateimage(app,sRGB);
                
            end
        end

        % Button pushed function: Button_10
        function AWB(app, event)
            R = app.im(:,:,1);G = app.im(:,:,2);B = app.im(:,:,3);
            Rave = mean2(R);
            Gave = mean2(G);
            Bave = mean2(B);
            K = (Rave + Gave + Bave) / 3;
            
            R_new=(K/Rave)*R;G_new=(K/Gave)*G;B_new=(K/Bave)*B; %与每个通道平均值与整体平均值比例乘以原通道值得到更加平衡的数值
            J = (cat(3,R_new,G_new,B_new));%合成图像
            updateimage(app,J);
        end

        % Button pushed function: Button_2
        function crop(app, event)
            %自带裁剪函数
            J = imcrop(app.im);
            if ~isempty(J)
                updateimage(app,J);
                close all
            end
            
        end

        % Button pushed function: Button_3
        function rotation(app, event)
            J = imrotate(app.im,-90,'bilinear','crop');
            updateimage(app,J);
        end

        % Button pushed function: Button_4
        function flip1(app, event)
            %镜像函数
            J = fliplr(app.im);
            updateimage(app,J);
        end

        % Button pushed function: Button_12
        function flip2(app, event)
            J = flipud(app.im);
            updateimage(app,J);
        end

        % Value changed function: Slider_1, Slider_2, Slider_3
        function chge(app, event)
            if (app.im_class == 3)
                H = 0.0005*app.Slider_3.Value+1;
                S = 0.01*app.Slider_2.Value+1;
                V = 0.01*app.Slider_1.Value+1;
                hsv = app.im_hsv;
                hsv(:,:,1) = hsv(:,:,1)*H; % 色调
                hsv(:,:,2) = hsv(:,:,2)*S;% 饱和度
                hsv(:,:,3) = hsv(:,:,3)*V; % 亮度
                J = hsv2rgb(hsv);
                updateimage(app,J);
            else
                app.im = app.im_tab*(1+app.Slider_1.Value/100);
                updateimage(app,app.im);
            end
        end

        % Callback function
        function sharpen(app, event)
            value= app.Slider_4.Value/50 ;
            sharpened_image = imsharpen(app.im_tab,'Amount',value);
            updateimage(app,sharpened_image);
        end

        % Callback function
        function adjust(app, event)
            value = app.Slider_5.Value;
            det = value/200;
            J = imadjust(app.im_tab,[det 0.99-det]);
            updateimage(app,J);
        end

        % Callback function
        function Button_13Pushed(app, event)

        end

        % Button down function: RedAxes
        function RedAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '图片编辑';
            app.UIFigure.Icon = 'appicon.png';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RedAxes = uiaxes(app.GridLayout);
            title(app.RedAxes, 'Red')
            xlabel(app.RedAxes, 'Intensity')
            ylabel(app.RedAxes, 'Pixels')
            app.RedAxes.XLim = [0 255];
            app.RedAxes.XTick = [0 128 255];
            app.RedAxes.ButtonDownFcn = createCallbackFcn(app, @RedAxesButtonDown, true);
            app.BlueAxes = uiaxes(app.GridLayout);
            title(app.BlueAxes, 'Blue')
            xlabel(app.BlueAxes, 'Intensity')
            ylabel(app.BlueAxes, 'Pixels')
            app.BlueAxes.XLim = [0 255];
            app.BlueAxes.XTick = [0 128 255];
            app.GreenAxes = uiaxes(app.GridLayout);
            title(app.GreenAxes, 'Green')
            xlabel(app.GreenAxes, 'Intensity')
            ylabel(app.GreenAxes, 'Pixels')
            app.GreenAxes.XLim = [0 255];
            app.GreenAxes.XTick = [0 128 255];
            app.ImageAxes = uiaxes(app.GridLayout);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab_1 = uitab(app.TabGroup);
            app.Tab_1.SizeChangedFcn = createCallbackFcn(app, @secTab, true);
            app.Tab_1.Title = '图像处理';
            app.GridLayout4 = uigridlayout(app.Tab_1);
            app.Button_5 = uibutton(app.GridLayout4, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @denoise, true);
            app.Button_5.Text = '降噪';
            app.Button_10 = uibutton(app.GridLayout4, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @AWB, true);
            app.Button_10.Text = '自动白平衡';
            app.Button_6 = uibutton(app.GridLayout4, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @CLAHE, true);
            app.Button_6.Text = '自动对比度增强';
            app.Button_13 = uibutton(app.GridLayout4, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @gamma, true);
            app.Button_13.Text = '伽马矫正';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.SizeChangedFcn = createCallbackFcn(app, @secTab, true);
            app.Tab_2.Title = '图形调节';
            app.GridLayout2 = uigridlayout(app.Tab_2);
            app.Button_2 = uibutton(app.GridLayout2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @crop, true);
            app.Button_2.Text = '裁剪';
            app.Button_3 = uibutton(app.GridLayout2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @rotation, true);
            app.Button_3.Text = '旋转';
            app.Button_12 = uibutton(app.GridLayout2, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @flip2, true);
            app.Button_12.Text = '镜像(垂直)';
            app.Button_4 = uibutton(app.GridLayout2, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @flip1, true);
            app.Button_4.Text = '镜像(水平)';
            app.HSVTab = uitab(app.TabGroup);
            app.HSVTab.SizeChangedFcn = createCallbackFcn(app, @secTab, true);
            app.HSVTab.Title = 'HSV调节';
            app.GridLayout3 = uigridlayout(app.HSVTab);
            app.Slider_1 = uislider(app.GridLayout3);
            app.Slider_1.Limits = [-100 100];
            app.Slider_1.ValueChangedFcn = createCallbackFcn(app, @chge, true);
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '亮度';
            app.Slider_2 = uislider(app.GridLayout3);
            app.Slider_2.Limits = [-100 100];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @chge, true);
            app.Label_3 = uilabel(app.GridLayout3);
            app.Label_3.Text = '饱和度';
            app.Slider_3 = uislider(app.GridLayout3);
            app.Slider_3.Limits = [-100 100];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @chge, true);
            app.Label_4 = uilabel(app.GridLayout3);
            app.Label_4.Text = '色调';
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = '导入图像';
            app.Button_8 = uibutton(app.GridLayout, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @compare, true);
            app.Button_8.Text = '对比';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @recovery, true);
            app.Button_9.Text = '复原';
            app.Button_11 = uibutton(app.GridLayout, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @export, true);
            app.Button_11.Text = '导出图像';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DealPicture_Gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end